classdef RenderSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RenderSettingsUIFigure  matlab.ui.Figure
        FormatDropDownLabel     matlab.ui.control.Label
        FormatDropDown          matlab.ui.control.DropDown
        LocationEditFieldLabel  matlab.ui.control.Label
        LocationEditField       matlab.ui.control.EditField
        BrowseButton            matlab.ui.control.Button
        DoneButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
        [file,path] = uiputfile('.jpg');
        app.RenderSettingsUIFigure.Visible = 'off';
        app.RenderSettingsUIFigure.Visible = 'on';
        if isequal(file,0)
            %user cancelled file browser
        else
            app.LocationEditField.Value=strcat(path,file);
        end
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
        value = app.FormatDropDown.Value;
        oldFilename = app.LocationEditField.Value;
        charRemover=1;
        while charRemover==1
            if oldFilename(end)=='.'
                charRemover=0;
            end
            oldFilename(end)='';
        end
        oldFilename=strcat(oldFilename,value);
        app.LocationEditField.Value=oldFilename;
        
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            updateRenderLocation(app.CallingApp,app.LocationEditField.Value);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RenderSettingsUIFigure = uifigure('Visible', 'off');
            app.RenderSettingsUIFigure.Name = 'Render Settings';
            app.FormatDropDownLabel = uilabel(app.RenderSettingsUIFigure);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.RenderSettingsUIFigure);
            app.FormatDropDown.Items = {'JPEG Sequence', 'PNG Sequence', 'TIFF Sequence', ''};
            app.FormatDropDown.ItemsData = {'.jpg', '.png', '.tiff'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = '.jpg';
            app.LocationEditFieldLabel = uilabel(app.RenderSettingsUIFigure);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.RenderSettingsUIFigure, 'text');
            app.BrowseButton = uibutton(app.RenderSettingsUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DoneButton = uibutton(app.RenderSettingsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.RenderSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RenderSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RenderSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RenderSettingsUIFigure)
        end
    end
end